classdef AdhesionAssayForceBrowser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AdhesionAssayForceBrowserUIFigure  matlab.ui.Figure
        FileMenu                  matlab.ui.container.Menu
        OpenAdhesiondatasetMenu   matlab.ui.container.Menu
        SaveAsMenu                matlab.ui.container.Menu
        CloseAdhesionDatasetMenu  matlab.ui.container.Menu
        ExitMenu                  matlab.ui.container.Menu
        EditMenu                  matlab.ui.container.Menu
        ConfigMenu                matlab.ui.container.Menu
        HelpMenu                  matlab.ui.container.Menu
        AboutMenu                 matlab.ui.container.Menu
        LeftPanel                 matlab.ui.container.Panel
        bootstrapCheckBox         matlab.ui.control.CheckBox
        confintCheckBox           matlab.ui.control.CheckBox
        fitcurveCheckBox          matlab.ui.control.CheckBox
        datapointsCheckBox        matlab.ui.control.CheckBox
        OneModeButton             matlab.ui.control.Button
        ActiveFitData             matlab.ui.control.Table
        AdhesionTable             matlab.ui.control.Table
    end

    
    properties (Access = private)
        ForcePlot       % Handle to force plot used inside the app        
        PlotOpts        % Description
        OpenedFilename  % Filename that's opened (put here for default save filename)
        AdhesionDataset % Pristine copy of the opened data file. Modifications are made outside this variable
    end
    
    methods (Access = private)
        
        function Data = update_ForceFitTable(app, newfit, Curve, AdhesionSelectedRow)

%             Data = app.AdhesionTable.Data;
% 
%             Data.FitObject{AdhesionSelectedRow} = newfit.FitObject;
%             Data.sse(AdhesionSelectedRow) = newfit.sse;
%             Data.rsquare(AdhesionSelectedRow) = newfit.rsquare;
%             Data.dfe(AdhesionSelectedRow) = newfit.dfe;
%             Data.adjrsquare(AdhesionSelectedRow) = newfit.adjrsquare;
%             Data.rmse(AdhesionSelectedRow) = newfit.rmse;
%             Data.a(AdhesionSelectedRow) = newfit.a;
%             Data.aconf(AdhesionSelectedRow,:) = newfit.aconf;
%             Data.am(AdhesionSelectedRow) = newfit.am;
%             Data.amconf(AdhesionSelectedRow,:) = newfit.amconf;
%             Data.as(AdhesionSelectedRow) = newfit.as;
%             Data.asconf(AdhesionSelectedRow,:) = newfit.asconf;
%             Data.Nterms(AdhesionSelectedRow,:)  = newfit.Nterms;
% 
% %             try
%                 Data.bm(AdhesionSelectedRow) = newfit.bm;
%                 Data.bmconf(AdhesionSelectedRow,:) = newfit.bmconf;
%                 Data.bs(AdhesionSelectedRow) = newfit.bs;
%                 Data.bsconf(AdhesionSelectedRow,:) = newfit.bsconf;
% %             catch
% %                 Data.bm(AdhesionSelectedRow) = NaN;
% %                 Data.bmconf(AdhesionSelectedRow,:) = [NaN NaN];
% %                 Data.bs(AdhesionSelectedRow) = NaN;
% %                 Data.bsconf(AdhesionSelectedRow,:) = [NaN NaN];
% %             end
% 
%             Data.RawData{AdhesionSelectedRow} = Curve;
% 
%             if newfit.a > 0.5
%                 Data.ForceFit(AdhesionSelectedRow) = newfit.am;
%                 Data.confForceFit(AdhesionSelectedRow,:) = newfit.amconf;
%             else
%                 Data.ForceFit(AdhesionSelectedRow) = newfit.bm;
%                 Data.confForceFit(AdhesionSelectedRow,:) = newfit.bmconf;
%             end  
% 
%             assignin("base", "AdhesionAppDataSet", Data);
        end
        
        function results = update_ForceFitPlot(app)
            selection = app.AdhesionTable.Selection;
            
            selData = app.AdhesionTable.Data(selection,:);
            ActiveFitDataOut = selData;

            assignin("base", "ActiveFitDataOut", ActiveFitDataOut);

            ba_plot_fit(selData, app.ForcePlot, app.PlotOpts);

            results = 0;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: OpenAdhesiondatasetMenu
        function OpenAdhesiondatasetMenuSelected(app, event)
            [File, Path, fidx] = uigetfile({'*.mat;*.AdhesionDataset.mat';'*.mat';'*.AdhesionDataset.mat';'*.*'}, ...
                                      'Select File(s) to Open', ...
                                      'MultiSelect', 'off');

            app.OpenedFilename = fullfile(Path, File);
            app.AdhesionDataset = load(app.OpenedFilename);
            
            if isfield(app.AdhesionDataset, 'ForceFitTable')
                ForceFitDataT = app.AdhesionDataset.ForceFitTable;     
            elseif isfield(app.AdhesionDataset, 'B')
                ForceFitDataT = app.AdhesionDataset.B.ForceFitTable;
            end

            app.AdhesionTable.Data = ForceFitDataT;
            app.AdhesionTable.ColumnName = ForceFitDataT.Properties.VariableNames;            
            drawnow;
            app.AdhesionTable.RowName = 'numbered';            
            app.AdhesionTable.SelectionType = "row";
            drawnow;
            
            app.PlotOpts = 'dmelc';
            app.datapointsCheckBox.Value = true;
            app.fitcurveCheckBox.Value = true;
            app.confintCheckBox.Value = true;
            app.bootstrapCheckBox.Value = false;
            app.ForcePlot = figure;

            assignin("base", "AdhesionAppDataSet", ForceFitDataT);
            logentry(['Loaded file: ' File]);
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            close(app.ForcePlot);
            app.delete;
        end

        % Callback function
        function ReduceFitButtonPushed(app, event)


        end

        % Selection changed function: AdhesionTable
        function AdhesionTableSelectionChanged(app, event)

            update_ForceFitPlot(app);

        end

        % Cell edit callback: ActiveFitData
        function ActiveFitDataCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

            AdhesionSelectedRow = app.AdhesionTable.Selection;

            Curve = event.Source.Data;
            Data = app.AdhesionTable.Data;

            inc = Curve.IncludeInFit;

            force = Curve.Force;
            force_error = Curve.ForceInterval;
            pct_left = Curve.FractionLeft;
            weights = Curve.Weights;
            
            Nterms = app.AdhesionTable.Data.Nterms(AdhesionSelectedRow);

            
            % newfit = ba_fit_erf(log10(force(inc)), pct_left(inc), weights(inc), Nterms, StartingPoint);

            ba_plot_fit(newfit.FitObject, force(inc), force_error(inc), pct_left(inc), app.ForcePlot);
            figure(app.ForcePlot)
            hold on
                plot(log10(force(~inc)), pct_left(~inc), 'kx');
            hold off

            Data = update_ForceFitTable(app, newfit, Curve, AdhesionSelectedRow);

            app.AdhesionTable.Data = Data;
            assignin("base", "ActiveFitDataOut", ActiveFitDataOut);
        end

        % Menu selected function: SaveAsMenu
        function SaveAsMenuSelected(app, event)
            
            DataOut = app.AdhesionDataset;
            DataOut.ForceFitTable = app.AdhesionTable.Data;
            
            DefaultSaveName = strrep(app.OpenedFilename,'.mat', '');
            DefaultSaveName = [DefaultSaveName, '.UIedited.mat'];
            [File, Path, fidx] = uiputfile({'*.UIedited.mat', 'AdhesionUI edited files (*.UIedited.mat)' ; ...
                                           '*.*', 'All Files (*.*)'}, ...
                                           'Save As...', ...
                                           DefaultSaveName);

            savename = fullfile(Path, File);
            save(savename,'-STRUCT', "DataOut",  '-mat');
    
            logentry(['Saved file: ' File]);
        end

        % Button pushed function: OneModeButton
        function OneModeButtonPushed(app, event)
             AdhesionSelectedRow = app.AdhesionTable.Selection;

            Curve = app.AdhesionTable.Data.RawData{AdhesionSelectedRow};


            inc = Curve.IncludeInFit;

            force = Curve.Force;
            force_error = Curve.ForceInterval;
            pct_left = Curve.FractionLeft;
            weights = Curve.Weights;
            
            Nterms = 1;
            
            newfit = ba_fit_erf(log10(force(inc)), pct_left(inc), weights(inc), Nterms, StartingPoint);

            ba_plot_fit(newfit.FitObject, force(inc), force_error(inc), pct_left(inc), app.ForcePlot);
            figure(app.ForcePlot)
            hold on
                plot(log10(force(~inc)), pct_left(~inc), 'kx');
            hold off

            app.AdhesionTable.Data = update_ForceFitTable(app, newfit, Curve, AdhesionSelectedRow);
        end

        % Value changed function: datapointsCheckBox
        function datapointsCheckBoxValueChanged(app, event)
            value = app.datapointsCheckBox.Value;
            if value 
                % add "datapoints" to the plot options, BUT if 'd' is 
                % already there, remove it before adding it back
                app.PlotOpts = strcat(strrep(app.PlotOpts,'d',''), 'd');
            else
                % remove "datapoints" from the plot options
                app.PlotOpts = strrep(app.PlotOpts,'d','');
            end
        end

        % Value changed function: fitcurveCheckBox
        function fitcurveCheckBoxValueChanged(app, event)
            value = app.fitcurveCheckBox.Value;
            if value 
                % add "fitcuve" to the plot options, BUT if 'm' is 
                % already there, remove it before adding it back
                app.PlotOpts = strcat(strrep(app.PlotOpts,'m',''), 'm');
            else
                % remove "datapoints" from the plot options
                app.PlotOpts = strrep(app.PlotOpts,'m','');
            end
            results = update_ForceFitPlot(app);
        end

        % Value changed function: confintCheckBox
        function confintCheckBoxValueChanged(app, event)
            value = app.confintCheckBox.Value;
            if value 
                % add "errorbars/confidence intervals" to the plot options, BUT if 'e' is 
                % already there, remove it before adding it back
                app.PlotOpts = strcat(strrep(app.PlotOpts,'e',''), 'e');
            else
                % remove "datapoints" from the plot options
                app.PlotOpts = strrep(app.PlotOpts,'e','');
            end
            results = update_ForceFitPlot(app);
        end

        % Value changed function: bootstrapCheckBox
        function bootstrapCheckBoxValueChanged(app, event)
            value = app.bootstrapCheckBox.Value;
            if value 
                % add "bootstrap fits" to the plot options, BUT if 'b' is 
                % already there, remove it before adding it back
                app.PlotOpts = strcat(strrep(app.PlotOpts,'b',''), 'b');
            else
                % remove "datapoints" from the plot options
                app.PlotOpts = strrep(app.PlotOpts,'b','');
            end
            results = update_ForceFitPlot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AdhesionAssayForceBrowserUIFigure = uifigure('Visible', 'off');
            app.AdhesionAssayForceBrowserUIFigure.Name = 'Adhesion Assay Force Browser';
            app.FileMenu = uimenu(app.AdhesionAssayForceBrowserUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenAdhesiondatasetMenu = uimenu(app.FileMenu);
            app.OpenAdhesiondatasetMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenAdhesiondatasetMenuSelected, true);
            app.OpenAdhesiondatasetMenu.Accelerator = 'o';
            app.OpenAdhesiondatasetMenu.Text = 'Open Adhesion dataset';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsMenuSelected, true);
            app.SaveAsMenu.Text = 'Save As...';
            app.CloseAdhesionDatasetMenu = uimenu(app.FileMenu);
            app.CloseAdhesionDatasetMenu.Accelerator = 'w';
            app.CloseAdhesionDatasetMenu.Text = 'Close Adhesion Dataset';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Accelerator = 'q';
            app.ExitMenu.Text = 'Exit';
            app.EditMenu = uimenu(app.AdhesionAssayForceBrowserUIFigure);
            app.EditMenu.Text = 'Edit';
            app.ConfigMenu = uimenu(app.EditMenu);
            app.ConfigMenu.Accelerator = ',';
            app.ConfigMenu.Text = 'Config';
            app.HelpMenu = uimenu(app.AdhesionAssayForceBrowserUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.Text = 'About';
            app.LeftPanel = uipanel(app.AdhesionAssayForceBrowserUIFigure);
            app.AdhesionTable = uitable(app.LeftPanel);
            app.AdhesionTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.AdhesionTable.ColumnRearrangeable = 'on';
            app.AdhesionTable.RowName = {};
            app.AdhesionTable.ColumnSortable = true;
            app.AdhesionTable.SelectionType = 'row';
            app.AdhesionTable.ColumnEditable = true;
            app.AdhesionTable.SelectionChangedFcn = createCallbackFcn(app, @AdhesionTableSelectionChanged, true);
            app.ActiveFitData = uitable(app.LeftPanel);
            app.ActiveFitData.ColumnName = '';
            app.ActiveFitData.RowName = {};
            app.ActiveFitData.ColumnSortable = true;
            app.ActiveFitData.ColumnEditable = true;
            app.ActiveFitData.CellEditCallback = createCallbackFcn(app, @ActiveFitDataCellEdit, true);
            app.OneModeButton = uibutton(app.LeftPanel, 'push');
            app.OneModeButton.ButtonPushedFcn = createCallbackFcn(app, @OneModeButtonPushed, true);
            app.OneModeButton.Enable = 'off';
            app.OneModeButton.Text = 'Reduce to one mode';
            app.datapointsCheckBox = uicheckbox(app.LeftPanel);
            app.datapointsCheckBox.ValueChangedFcn = createCallbackFcn(app, @datapointsCheckBoxValueChanged, true);
            app.datapointsCheckBox.Text = 'datapoints';
            app.datapointsCheckBox.Value = true;
            app.fitcurveCheckBox = uicheckbox(app.LeftPanel);
            app.fitcurveCheckBox.ValueChangedFcn = createCallbackFcn(app, @fitcurveCheckBoxValueChanged, true);
            app.fitcurveCheckBox.Text = 'fit curve';
            app.fitcurveCheckBox.Value = true;
            app.confintCheckBox = uicheckbox(app.LeftPanel);
            app.confintCheckBox.ValueChangedFcn = createCallbackFcn(app, @confintCheckBoxValueChanged, true);
            app.confintCheckBox.Text = 'conf int';
            app.confintCheckBox.Value = true;
            app.bootstrapCheckBox = uicheckbox(app.LeftPanel);
            app.bootstrapCheckBox.ValueChangedFcn = createCallbackFcn(app, @bootstrapCheckBoxValueChanged, true);
            app.bootstrapCheckBox.Text = 'bootstrap';
            % Show the figure after all components are created
            app.AdhesionAssayForceBrowserUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AdhesionAssayForceBrowser

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.AdhesionAssayForceBrowserUIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.AdhesionAssayForceBrowserUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AdhesionAssayForceBrowserUIFigure)
        end
    end
end